classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ModePlotsPanel                matlab.ui.container.Panel
        PlotButton                    matlab.ui.control.Button
        MultiplierEditField           matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel      matlab.ui.control.Label
        GifOutButton                  matlab.ui.control.Button
        SpecificFrequencyHzEditField  matlab.ui.control.NumericEditField
        SpecificFrequencyHzEditFieldLabel  matlab.ui.control.Label
        ValidatesensorsButton         matlab.ui.control.Button
        ClearConsoleButton            matlab.ui.control.Button
        ConsoleOutput                 matlab.ui.control.TextArea
        AccelerometersPanel           matlab.ui.container.Panel
        DataTypeButtonGroup           matlab.ui.container.ButtonGroup
        FrequencyButton               matlab.ui.control.RadioButton
        TimeButton                    matlab.ui.control.RadioButton
    end


    properties (Access = private)
        AccelBlocks    % handles of UI components
        SensorData     % stores fixed data: position and axis type
        ImportaccelerometerpositionsButton
        ModePlot
        ModeSelectionSlider
        ModeSelectionSliderLabel
        allSensorsOK
        frequencyList
    end

    methods (Access = private)

        function plotMode(app, saveGif)
            if nargin < 2
                saveGif = false;
            end

            if ~app.allSensorsOK
                logToConsole(app, "⚠️ There are missing sensor data.");
                return;
            end

            f_target = app.SpecificFrequencyHzEditField.Value;
            multiplier = app.MultiplierEditField.Value;

            t = linspace(0, 4*pi, 100)';

            allX = []; allY = []; allZ = [];
            for i = 1:numel(app.SensorData)
                sensor = app.SensorData{i};
                freq = sensor.freq;

                [Xcos, Xsin] = getMotionSafe(sensor, 'X', freq, f_target);
                [Ycos, Ysin] = getMotionSafe(sensor, 'Y', freq, f_target);
                [Zcos, Zsin] = getMotionSafe(sensor, 'Z', freq, f_target);

                motion = [Xcos*cos(t) + Xsin*sin(t), Ycos*cos(t) + Ysin*sin(t), Zcos*cos(t) + Zsin*sin(t)];
                app.SensorData{i}.Movement = sensor.Position + motion * multiplier;

                mov = app.SensorData{i}.Movement;
                allX = [allX; mov(:,1)];
                allY = [allY; mov(:,2)];
                allZ = [allZ; mov(:,3)];
            end

            padding = 0.1;
            xRange = max(max(allX) - min(allX),100);
            yRange = max(max(allY) - min(allY),100);
            zRange = max(max(allZ) - min(allZ),100);
            threshold = 0.1 * max([xRange, yRange, zRange]);

            xlimVals = [min(allX) - padding * xRange, max(allX) + padding * xRange];
            ylimVals = [min(allY) - padding * yRange, max(allY) + padding * yRange];
            zlimVals = [min(allZ) - padding * zRange, max(allZ) + padding * zRange];

            basePositions = cell2mat(cellfun(@(s) s.Position, app.SensorData, 'UniformOutput', false));
            basePositions = reshape(basePositions',3,[])';
            linkPairs = computeProximityLinks(basePositions, threshold);

            % --- Get user view angle ---
            try
                camview = get(app.ModePlot, 'View');  % user's current view
            catch
                camview = [45 30];  % fallback
            end

            % --- GIF file naming ---
            if saveGif
                f_target = round(f_target, 3);
                idx = find(abs(app.frequencyList - f_target) < 1e-3, 1);
                if ~isempty(idx)
                    gifName = sprintf('Mode_Shape_%d-%.3fHz.gif', idx, f_target);
                    gifTitle = sprintf('Mode Shape %d at %.3f Hz', idx, f_target);
                else
                    gifName = sprintf('Behavior-%.3fHz.gif', f_target);
                    gifTitle = sprintf('Behavior at %.3f Hz', f_target);
                end

                gifFig = figure('Visible', 'off', 'Position', [100 100 800 600]);
                gifAx = axes(gifFig);
                view(gifAx, camview);
                axis(gifAx, 'equal');
                xlim(gifAx, xlimVals);
                ylim(gifAx, ylimVals);
                zlim(gifAx, zlimVals);
                grid(gifAx, 'on');
                title(gifAx, gifTitle, 'FontSize', 16);
                xlabel(gifAx, 'X'); ylabel(gifAx, 'Y'); zlabel(gifAx, 'Z');
            end

            nFrames = size(app.SensorData{1}.Movement, 1);
            for k = 1:nFrames
                if saveGif
                    cla(gifAx);
                    hold(gifAx, 'on');
                    currXYZ = zeros(numel(app.SensorData), 3);
                    for i = 1:numel(app.SensorData)
                        currXYZ(i,:) = app.SensorData{i}.Movement(k,:);
                    end
                    plot3(gifAx, currXYZ(:,1), currXYZ(:,2), currXYZ(:,3), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b');

                    for m = 1:size(linkPairs, 1)
                        i = linkPairs(m,1); j = linkPairs(m,2);
                        p_i = currXYZ(i,:); p_j = currXYZ(j,:);
                        plot3(gifAx, [p_i(1) p_j(1)], [p_i(2) p_j(2)], [p_i(3) p_j(3)], '-', 'Color', [0.4 0.4 0.4], 'LineWidth', 2);
                    end

                    drawnow;
                    frame = getframe(gifFig);
                    [im, cmap] = rgb2ind(frame2im(frame), 256);
                    if k == 1
                        imwrite(im, cmap, gifName, 'gif', 'LoopCount', Inf, 'DelayTime', 0.05);
                        logToConsole(app, "Gif export is started");
                    elseif k == nFrames
                        imwrite(im, cmap, gifName, 'gif', 'WriteMode', 'append', 'DelayTime', 0.05);
                        logToConsole(app, "Gif export is finished");
                    else
                        imwrite(im, cmap, gifName, 'gif', 'WriteMode', 'append', 'DelayTime', 0.05);
                    end
                else
                    delete(app.ModePlot.Children);
                    hold(app.ModePlot, 'on');
                    grid(app.ModePlot, 'on');
                    title(app.ModePlot, sprintf('Behavior at %.2f Hz', f_target), 'FontSize', 16);

                    currXYZ = zeros(numel(app.SensorData), 3);
                    for i = 1:numel(app.SensorData)
                        p = app.SensorData{i}.Movement(k,:);
                        currXYZ(i,:) = p;
                        plot3(app.ModePlot, p(1), p(2), p(3), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
                    end

                    for m = 1:size(linkPairs, 1)
                        i = linkPairs(m,1); j = linkPairs(m,2);
                        p_i = currXYZ(i,:); p_j = currXYZ(j,:);
                        plot3(app.ModePlot, [p_i(1) p_j(1)], [p_i(2) p_j(2)], [p_i(3) p_j(3)], '-', 'Color', [0.4 0.4 0.4], 'LineWidth', 2);
                    end

                    axis(app.ModePlot, 'equal');
                    xlim(app.ModePlot, xlimVals);
                    ylim(app.ModePlot, ylimVals);
                    zlim(app.ModePlot, zlimVals);
                    view(app.ModePlot, camview);
                    drawnow;
                end
            end

            if ~saveGif
                logToConsole(app, sprintf("✅ Behavior plotted at %.2f Hz", f_target));
            end

            function [c, s] = getMotionSafe(sensor, axis, freq, f_target)
                ampField = [axis '_amp'];
                phaseField = [axis '_phase'];
                if isfield(sensor, ampField) && isfield(sensor, phaseField)
                    amp = interp1(freq, sensor.(ampField), f_target, 'linear', 0);
                    phase = interp1(freq, sensor.(phaseField), f_target, 'linear', 0);
                    c = amp * cos(phase);
                    s = amp * sin(phase);
                else
                    c = 0; s = 0;
                end
            end

            function pairs = computeProximityLinks(positions, thresh)
                n = size(positions, 1);
                pairs = [];
                for a = 1:n
                    for b = a+1:n
                        if norm(positions(a,:) - positions(b,:)) < thresh
                            pairs(end+1,:) = [a b]; %#ok<AGROW>
                        end
                    end
                end
            end
        end


        function onAllSensorsValidated(app)
            logToConsole(app, "Proceeding to next step...");
            % Insert your next-step code here
            detectNaturalFrequencies(app)
        end

        function detectNaturalFrequencies(app)
            % Setup
            freq_reference = [];
            axisLabels = {'X', 'Y', 'Z'};
            processed = struct;

            % Step 1: Extract amplitude-phase data (from freq or time)
            for i = 1:numel(app.SensorData)
                sensor = app.SensorData{i};

                % Case: frequency domain
                if isfield(sensor, 'freq')
                    freq = sensor.freq;
                    if isempty(freq_reference)
                        freq_reference = freq;
                    end

                % Case: time domain (extract from time and timedata fields)
                elseif isfield(sensor, 'time')
                    dt = sensor.time(2) - sensor.time(1); %mean(diff(sensor.time));
                    Fs = 1/dt;
                    N = length(sensor.time);
                    freq = Fs*(0:floor(N/2))/N;
                    if isempty(freq_reference)
                        freq_reference = freq;
                    end
                    for ax = 1:3
                        label = axisLabels{ax};
                        field = [label '_timedata'];
                        if isfield(sensor, field)
                            sig = sensor.(field);
                            % pre processing
                            sig = sig.*my_tukeywin(N,0.02);
                            sig = sig - mean(sig);
                            Y = fft(sig);
                            Y = Y(1:length(freq));
                            sensor.([label '_amp']) = abs(Y(:));
                            sensor.([label '_phase']) = angle(Y(:));
                        end
                    end

                    sensor.freq = freq(:);
                    app.SensorData{i} = sensor;  % update sensor
                else
                    continue;
                end

                % Now use X_amp, Y_amp, Z_amp for processing (same as before)
                for ax = 1:3
                    label = axisLabels{ax};
                    ampField = [label '_amp'];
                    phaseField = [label '_phase'];

                    if isfield(sensor, ampField) && isfield(sensor, phaseField)
                        realPart = sensor.(ampField) .* cos(sensor.(phaseField));
                        imagPart = sensor.(ampField) .* sin(sensor.(phaseField));
                        mag = sqrt(realPart.^2 + imagPart.^2);
                        if app.TimeButton.Value
                            smoothed = smoothdata(mag, 'movmean',9/sensor.time(end)*2^ceil(log2(sensor.time(end))));
                        else
                            smoothed = smoothdata(mag, 'movmean', length(mag)/50);
                        end
                        normalized = smoothed(:)/smoothed(1);

                        if ~isfield(processed, label)
                            processed.(label) = normalized;
                        else
                            processed.(label)(:, end+1) = normalized;
                        end
                    end
                end
            end

            % Step 2: For each axis, average
            finalPeaks = [];

            for ax = 1:3
                label = axisLabels{ax};
                if ~isfield(processed, label)
                    continue;
                end

                allData = processed.(label);
                avg = sum(allData, 2, 'omitnan') ./ sum(~isnan(allData), 2);

                % Step 3: Peak detection
                peakIdx = find(diff(sign(diff(avg))) == -2) + 1;
                minProm = 0.1 * max(avg);
                validIdx = peakIdx(avg(peakIdx) > minProm & avg(peakIdx) > 0.8);

                % Step 4: Frequency separation filtering
                selectedFreqs = [];
                for i = 1:length(validIdx)
                    f = freq_reference(validIdx(i));
                    amp = avg(validIdx(i));
                    if isempty(selectedFreqs) || f > 1.05 * selectedFreqs(end)
                        selectedFreqs(end+1) = f;
                    end
                end

                finalPeaks = [finalPeaks; selectedFreqs'];
            end

            % Step 5: Merge all axis peak frequencies
            finalPeaks = sort(finalPeaks);
            merged = [];
            for i = 1:length(finalPeaks)
                f = finalPeaks(i);
                if isempty(merged) || all(abs(merged - f) > 0.05 * merged)
                    merged(end+1) = f;
                end
            end

            app.frequencyList = merged;

            if length(app.frequencyList) > 15
                app.ModeSelectionSlider.Limits = [1 15];
                app.ModeSelectionSlider.MajorTicks = 1:15;
            elseif ~isempty(app.frequencyList)
                app.ModeSelectionSlider.Limits = [1 length(app.frequencyList)];
                app.ModeSelectionSlider.MajorTicks = 1:length(app.frequencyList);
            end
            app.ModeSelectionSlider.Value = 1;
            onSliderChanged(app)

            function window = my_tukeywin(L,r)
                x = linspace(0,1,L);
                low_index = x<(r/2);
                high_index = x>(1-r/2);
                window = ones(1, L);
                window(low_index) = 1/2*(1+cos(2*pi/r*(x(low_index)-r/2)));
                window(high_index) = 1/2*(1+cos(2*pi/r*(x(high_index)-1+r/2)));
                window = window(:);
            end

        end

        function createAccelerometerBlocks(app)

            % Parameters
            numSensors = 20;
            blockHeight = 150;
            spacing = 10;
            buttonHeight = 30;
            buttonSpacing = 10;

            % Toplam panel yüksekliği
            totalHeight = buttonHeight + buttonSpacing + numSensors * (blockHeight + spacing);

            % Import positions button (üstte)
            buttonY = totalHeight - buttonHeight - buttonSpacing;
            app.ImportaccelerometerpositionsButton = uibutton(app.AccelerometersPanel, 'push');
            app.ImportaccelerometerpositionsButton.BackgroundColor = [0.851 0.9294 1];
            app.ImportaccelerometerpositionsButton.Position = [10 buttonY 310 buttonHeight];
            app.ImportaccelerometerpositionsButton.Text = 'Import accelerometer positions';

            % Preallocate
            app.AccelBlocks = struct;

            % Blokları alt alta yerleştir
            for i = 1:numSensors
                yPos = buttonY - i * (blockHeight + spacing);

                block = uipanel(app.AccelerometersPanel);
                block.Title = sprintf("%d:", i);
                block.Position = [10 yPos 310 blockHeight];  % fits within scroll-safe margin

                % --- X Y Z Fields --- %
                uilabel(block, 'Text', 'X:', 'Position', [20 100 20 25]);
                xField = uieditfield(block, 'numeric', 'Position', [40 100 60 25]);

                uilabel(block, 'Text', 'Y:', 'Position', [120 100 20 25]);
                yField = uieditfield(block, 'numeric', 'Position', [140 100 60 25]);

                uilabel(block, 'Text', 'Z:', 'Position', [220 100 20 25]);
                zField = uieditfield(block, 'numeric', 'Position', [240 100 60 25]);

                % --- Axis Selection --- %
                axisGroup = uibuttongroup(block, 'Position', [10 45 290 50]);
                axisGroup.Title = 'Axis';
                uiradiobutton(axisGroup, 'Text', '3D', 'Position', [10 10 55 20]);
                uiradiobutton(axisGroup, 'Text', 'X',  'Position', [75 10 55 20]);
                uiradiobutton(axisGroup, 'Text', 'Y',  'Position', [150 10 55 20]);
                uiradiobutton(axisGroup, 'Text', 'Z',  'Position', [235 10 55 20]);
                axisGroup.SelectedObject = axisGroup.Children(4);  % default = 3D

                % --- Import Data Button --- %
                importBtn = uibutton(block, 'push', ...
                    'Text', 'Import Data', ...
                    'Position', [10 10 90 30], ...
                    'BackgroundColor',[1,0.98,0.804], ...
                    'Tooltip', 'Load sensor data from file');
                importBtn.ButtonPushedFcn = @(btn, event) importSensorData(app, i);

                % --- Fix Position Button --- %
                fixBtn = uibutton(block, 'push', ...
                    'Text', 'Fix Position', ...
                    'Position', [110 10 90 30], ...
                    'Tooltip', 'Store the position and axis selection');
                fixBtn.ButtonPushedFcn = @(btn,event) fixPosition(app, i);

                % --- Clear Button (light red) --- %
                clearBtn = uibutton(block, 'push', ...
                    'Text', 'Clear', ...
                    'Position', [210 10 90 30], ...
                    'BackgroundColor', [1 0.9 0.9], ...
                    'Tooltip', 'Reset position and axis selection');
                clearBtn.ButtonPushedFcn = @(btn,event) clearSensor(app, i);

                % Store handles
                app.AccelBlocks(i).Panel = block;
                app.AccelBlocks(i).XField = xField;
                app.AccelBlocks(i).YField = yField;
                app.AccelBlocks(i).ZField = zField;
                app.AccelBlocks(i).AxisGroup = axisGroup;
            end

        end

        function importSensorPositions(app)
            [file, path] = uigetfile({'*.txt;*.csv;*.xlsx', 'Position Files'}, ...
                'Select Sensor Positions File');
            if isequal(file, 0)
                logToConsole(app, "Position import cancelled.");
                return;
            end

            fullPath = fullfile(path, file);
            try
                data = readmatrix(fullPath);  % CSV, TXT veya XLSX desteklenir

                if size(data,2) ~= 3
                    logToConsole(app, "Position file must have 3 columns: X, Y, Z.");
                    return;
                end

                numSensors = min(size(data,1), numel(app.AccelBlocks));
                for i = 1:numSensors
                    app.AccelBlocks(i).XField.Value = data(i,1);
                    app.AccelBlocks(i).YField.Value = data(i,2);
                    app.AccelBlocks(i).ZField.Value = data(i,3);

                    % Optional: store position into SensorData without axis yet
                    if isempty(app.SensorData) || numel(app.SensorData) < i || ~isstruct(app.SensorData{i})
                        app.SensorData{i} = struct();
                    end
                    fixPosition(app, i)
                end

                logToConsole(app, sprintf("Imported positions for %d sensors.", numSensors));
            catch err
                logToConsole(app, sprintf("Failed to import sensor positions: %s", err.message));
            end
        end

        function importSensorData(app, idx)
            [file, path] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                sprintf('Import data for Accelerometer %d', idx));
            if isequal(file, 0)
                logToConsole(app, sprintf("Import cancelled for Accelerometer %d.", idx));
                return;
            end

            fullPath = fullfile(path, file);
            try
                % --- Get global data type (Time or Frequency) --- %
                if app.TimeButton.Value
                    dataType = 'time';
                elseif app.FrequencyButton.Value
                    dataType = 'frequency';
                else
                    error('No data type selected.');
                end

                data = readmatrix(fullPath);
                [rows, cols] = size(data);
                zeroData = zeros(rows,1);

                if cols < 2
                    logToConsole(app, sprintf("Less than 2 columns in the import file for Accelerometer %d.", idx));
                    logToConsole(app, sprintf("Import is NOT succesful for Accelerometer %d.", idx));
                    return;
                end

                axisGroup = app.AccelBlocks(idx).AxisGroup;
                axisSelection = axisGroup.SelectedObject.Text;
                
                if axisSelection == "3D"
                    if strcmp(dataType, 'time') && cols == 4
                        app.SensorData{idx} = struct();
                        fixPosition(app, idx)
                        app.SensorData{idx}.time = data(:,1);
                        if mean(abs(data(:,2))) > 10^-5
                            app.SensorData{idx}.X_timedata = data(:,2);
                        else
                            app.SensorData{idx}.X_timedata = zeroData;
                        end
                        if mean(abs(data(:,3))) > 10^-5
                            app.SensorData{idx}.Y_timedata = data(:,3);
                        else
                            app.SensorData{idx}.Y_timedata = zeroData;
                        end
                        if mean(abs(data(:,4))) > 10^-5
                            app.SensorData{idx}.Z_timedata = data(:,4);
                        else
                            app.SensorData{idx}.Y_timedata = zeroData;
                        end
                        app.SensorData{idx}.FileName = file;
                    elseif strcmp(dataType, 'frequency') && cols == 7
                        app.SensorData{idx} = struct();
                        fixPosition(app, idx)
                        app.SensorData{idx}.freq = data(:,1);
                        if mean(abs(data(:,2))) > 10^-5
                            app.SensorData{idx}.X_amp = data(:,2);
                            app.SensorData{idx}.X_phase = data(:,3);
                        else
                            app.SensorData{idx}.X_amp = zeroData;
                            app.SensorData{idx}.X_phase = zeroData;
                        end
                        if mean(abs(data(:,2))) > 10^-5
                            app.SensorData{idx}.Y_amp = data(:,4);
                            app.SensorData{idx}.Y_phase = data(:,5);
                        else
                            app.SensorData{idx}.Y_amp = zeroData;
                            app.SensorData{idx}.Y_phase = zeroData;
                        end
                        if mean(abs(data(:,2))) > 10^-5
                            app.SensorData{idx}.Z_amp = data(:,6);
                            app.SensorData{idx}.Z_phase = data(:,7);
                        else
                            app.SensorData{idx}.Z_amp = zeroData;
                            app.SensorData{idx}.Z_phase = zeroData;
                        end
                        app.SensorData{idx}.FileName = file;
                    else
                        logToConsole(app, sprintf("Amount of columns is wrong for Accelerometer %d.", idx));
                        logToConsole(app, sprintf("Import is NOT succesful for Accelerometer %d.", idx));
                        return;
                    end
                else
                    
                    fields = {'X', 'Y', 'Z'};
                    if strcmp(dataType, 'time') && cols == 2
                        app.SensorData{idx} = struct();
                        fixPosition(app, idx)
                        app.SensorData{idx}.time = data(:,1);
                        for k = 1:3
                            if axisSelection == fields{k} && mean(abs(data(:,2))) > 10^-5
                                app.SensorData{idx}.([fields{k} '_timedata']) = data(:,2);
                            else
                                app.SensorData{idx}.([fields{k} '_timedata']) = zeroData;
                            end
                        end
                        app.SensorData{idx}.FileName = file;
                    elseif strcmp(dataType, 'frequency') && cols == 3
                        app.SensorData{idx} = struct();
                        fixPosition(app, idx)
                        app.SensorData{idx}.freq = data(:,1);
                        for k = 1:3
                            if axisSelection == fields{k} && mean(abs(data(:,2))) > 10^-5
                                app.SensorData{idx}.([fields{k} '_amp']) = data(:,2);
                                app.SensorData{idx}.([fields{k} '_phase']) = data(:,3);
                            else
                                app.SensorData{idx}.([fields{k} '_amp']) = zeroData;
                                app.SensorData{idx}.([fields{k} '_phase']) = zeroData;
                            end
                        end
                        app.SensorData{idx}.FileName = file;
                    else
                        logToConsole(app, sprintf("Amount of columns is wrong for Accelerometer %d.", idx));
                        logToConsole(app, sprintf("Import is NOT succesful for Accelerometer %d.", idx));
                        return;
                    end
                end

                logToConsole(app, sprintf("Accelerometer %d imported [%s, %s] from %s", ...
                    idx, app.SensorData{idx}.AxisType, dataType, file));
            catch err
                logToConsole(app, sprintf("Import failed for Accelerometer %d: %s", ...
                    idx, err.message));
            end
        end

        function fixPosition(app, idx)
            x = app.AccelBlocks(idx).XField.Value;
            y = app.AccelBlocks(idx).YField.Value;
            z = app.AccelBlocks(idx).ZField.Value;
            axisGroup = app.AccelBlocks(idx).AxisGroup;
            axisSelection = axisGroup.SelectedObject.Text;

            % Save data
            app.SensorData{idx}.Position = [x, y, z];
            app.SensorData{idx}.AxisType = axisSelection;

            % Console output
            msg = sprintf("Accelerometer %d fixed. Position: [%.2f %.2f %.2f], Axis: %s", ...
                idx, x, y, z, axisSelection);
            logToConsole(app, msg);
        end

        function clearSensor(app, idx)
            % Reset values to default
            app.AccelBlocks(idx).XField.Value = 0;
            app.AccelBlocks(idx).YField.Value = 0;
            app.AccelBlocks(idx).ZField.Value = 0;

            % Reset axis selection to 3D
            axisButtons = app.AccelBlocks(idx).AxisGroup.Children;
            app.AccelBlocks(idx).AxisGroup.SelectedObject = ...
                findobj(axisButtons, 'Text', '3D');

            % Truly clear sensor data from memory
            if isfield(app.SensorData, num2str(idx))
                app.SensorData(idx) = [];
            else
                app.SensorData{idx} = [];  % fallback in case struct indexing fails
            end

            % Log to console
            logToConsole(app, sprintf("Accelerometer %d cleared.", idx));
        end

        function validateSensorData(app)
            logToConsole(app, "Validating all sensor data...");

            if numel(app.SensorData) > 0
                app.allSensorsOK = true;
            end

            for i = 1:numel(app.SensorData)
                data = app.SensorData{i};

                % Case: no data at all
                if isempty(data) || ~isstruct(data)
                    continue;
                end

                % Case: missing position or axis
                if ~isfield(data, 'Position') || isempty(data.Position) || ...
                        ~isfield(data, 'AxisType') || isempty(data.AxisType)
                    logToConsole(app, sprintf("Sensor %d ❌ Missing position or axis type.", i));
                    continue;
                end

                % Position and axis exist, now check for imported signal data
                hasImportData = any(contains(fieldnames(data), {'time', 'freq'}));

                if isfield(data, 'FileName')
                    filenameInfo = sprintf("Imported from %s", data.FileName);
                else
                    filenameInfo = "Imported (file unknown)";
                end

                if hasImportData
                    logToConsole(app, sprintf("Sensor %d ✅ OK: [%.2f %.2f %.2f], Axis: %s — %s", ...
                        i, data.Position(1), data.Position(2), data.Position(3), string(data.AxisType), filenameInfo));
                else
                    logToConsole(app, sprintf("Sensor %d ⚠️ Fixed position [%.2f %.2f %.2f], Axis: %s — No data imported", ...
                        i, data.Position(1), data.Position(2), data.Position(3), string(data.AxisType)));
                    app.allSensorsOK = false;
                end
            end

            if app.allSensorsOK
                logToConsole(app, "✅ All sensors validated successfully.");
                onAllSensorsValidated(app);
            else
                logToConsole(app, "⚠️ Not all sensors have valid data.");
            end

            logToConsole(app, "Sensor data validation completed.");
        end

        function clearConsole(app, ~)
            msg = 'Console cleared.';
            timestamp = datestr(now, 'HH:MM:SS');
            app.ConsoleOutput.Value = char(sprintf("[%s] %s", timestamp, msg));
            drawnow;
        end

        function logToConsole(app, msg)
            timestamp = datestr(now, 'HH:MM:SS');
            newLine = char(sprintf("[%s] %s", timestamp, msg));

            % Normalize current value as cell array of char
            current = app.ConsoleOutput.Value;
            if ischar(current)
                current = {current};  % char to cell
            elseif isstring(current)
                current = cellstr(current);  % string to cell
            elseif iscategorical(current)
                current = cellstr(string(current));  % categorical to cell
            end

            % Append message and update
            app.ConsoleOutput.Value = [{newLine}; current];
            drawnow;
        end

        function onSliderChanged(app)
            idx = round(app.ModeSelectionSlider.Value);
            app.ModeSelectionSlider.Value = idx;  % Snap to integer
            app.SpecificFrequencyHzEditField.Value = app.frequencyList(idx);
        end

        function arrangeUI(app)
            % Create ModeSelectionSliderLabel
            app.ModeSelectionSliderLabel = uilabel(app.ModePlotsPanel);
            app.ModeSelectionSliderLabel.HorizontalAlignment = 'center';
            app.ModeSelectionSliderLabel.WordWrap = 'on';
            app.ModeSelectionSliderLabel.FontSize = 16;
            app.ModeSelectionSliderLabel.Position = [840 570 120 40];
            app.ModeSelectionSliderLabel.Text = 'Mode Selection';

            % Create ModeSelectionSlider
            app.ModeSelectionSlider = uislider(app.ModePlotsPanel);
            app.ModeSelectionSlider.Limits = [1 15];
            app.ModeSelectionSlider.Orientation = 'vertical';
            app.ModeSelectionSlider.FontSize = 14;
            app.ModeSelectionSlider.Position(1:2) = [890 240]; % Only set left & bottom
            app.ModeSelectionSlider.Position(4) = 330;         % Height
            app.ModeSelectionSlider.Value = 1;

            app.ModeSelectionSlider.MajorTicks = 1:15;
            app.ModeSelectionSlider.MinorTicks = [];
            app.MultiplierEditField.Tooltip = 'Scales the mode shape amplitudes';

            app.ImportaccelerometerpositionsButton.ButtonPushedFcn = @(btn,event) importSensorPositions(app);
            app.ValidatesensorsButton.ButtonPushedFcn = @(btn,event) validateSensorData(app);
            app.ClearConsoleButton.ButtonPushedFcn = @(btn,event) clearConsole(app);
            app.PlotButton.ButtonPushedFcn = @(btn,event) plotMode(app);
            app.GifOutButton.ButtonPushedFcn = @(btn,event) plotMode(app, true);
            app.ModeSelectionSlider.ValueChangedFcn = @(s,e) onSliderChanged(app);
        end

        function createModePlotAxes(app)
            if ~isempty(app.ModePlot) && isvalid(app.ModePlot)
                delete(app.ModePlot);
            end

            app.ModePlot = uiaxes(app.ModePlotsPanel);
            app.ModePlot.Position = [20, 20, 800, 590];  % [left, bottom, width, height]
            title(app.ModePlot, 'Mode Shape Plot');
            xlabel(app.ModePlot, 'X');
            ylabel(app.ModePlot, 'Y');
            zlabel(app.ModePlot, 'Z');
            view(app.ModePlot, 3);
            grid(app.ModePlot, 'on');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ConsoleOutput)
            createAccelerometerBlocks(app);
            createModePlotAxes(app);
            arrangeUI(app);

            timestamp = datestr(now, 'HH:MM:SS');
            app.ConsoleOutput.Value = char(sprintf("[%s] %s", timestamp, 'Console ready.'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.TimeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.TimeButton.Text = 'Time';
            app.FrequencyButton = uiradiobutton(app.DataTypeButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.FrequencyButton.Value = true;
            app.AccelerometersPanel = uipanel(app.UIFigure);
            app.AccelerometersPanel.Title = 'Accelerometers';
            app.ConsoleOutput = uitextarea(app.UIFigure);
            app.ConsoleOutput.Editable = 'off';
            app.ClearConsoleButton = uibutton(app.UIFigure, 'push');
            app.ClearConsoleButton.Text = 'Clear Console';
            app.ValidatesensorsButton = uibutton(app.UIFigure, 'push');
            app.ValidatesensorsButton.Text = 'Validate sensors';
            app.ModePlotsPanel = uipanel(app.UIFigure);
            app.ModePlotsPanel.Title = 'Mode Plots';
            app.SpecificFrequencyHzEditFieldLabel = uilabel(app.ModePlotsPanel);
            app.SpecificFrequencyHzEditFieldLabel.Text = 'Specific Frequency [Hz]';
            app.SpecificFrequencyHzEditField = uieditfield(app.ModePlotsPanel, 'numeric');
            app.SpecificFrequencyHzEditField.Limits = [0 Inf];
            app.GifOutButton = uibutton(app.ModePlotsPanel, 'push');
            app.GifOutButton.Text = 'Gif Out';
            app.MultiplierEditFieldLabel = uilabel(app.ModePlotsPanel);
            app.MultiplierEditFieldLabel.Text = 'Multiplier';
            app.MultiplierEditField = uieditfield(app.ModePlotsPanel, 'numeric');
            app.MultiplierEditField.Limits = [0 Inf];
            app.MultiplierEditField.Value = 10;
            app.PlotButton = uibutton(app.ModePlotsPanel, 'push');
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end